classdef GitFit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GitFitLabel                  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        Panel_5                      matlab.ui.container.Panel
        Panel_6                      matlab.ui.container.Panel
        FINISHEDButton               matlab.ui.control.Button
        StepsEditField               matlab.ui.control.NumericEditField
        StepsEditFieldLabel          matlab.ui.control.Label
        YouBurnedkcalEditField       matlab.ui.control.NumericEditField
        YouBurnedkcalEditFieldLabel  matlab.ui.control.Label
        NextButton                   matlab.ui.control.Button
        Image2                       matlab.ui.control.Image
        HereisaSummaryofyourWorkoutLabel  matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        Panel_4                      matlab.ui.container.Panel
        STOPButton                   matlab.ui.control.Button
        PressSTARTButtonToBeginWorkoutLabel  matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        BACKButton_2                 matlab.ui.control.Button
        OKButton                     matlab.ui.control.Button
        EditField                    matlab.ui.control.NumericEditField
        BasedonyourBMIthesearetheaverageamountofstepsyouneedtotakeLabel  matlab.ui.control.Label
        BACKButton                   matlab.ui.control.Button
        NEXTButton                   matlab.ui.control.Button
        StatusLamp                   matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        BodyMassIndexBMIEditField    matlab.ui.control.NumericEditField
        BodyMassIndexBMILabel        matlab.ui.control.Label
        HackUTA2023Label             matlab.ui.control.Label
        PleaseEnterInformationPanel  matlab.ui.container.Panel
        Image_2                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        GETSTARTEDButton             matlab.ui.control.Button
        WeightlbsEditField           matlab.ui.control.NumericEditField
        WeightlbsEditFieldLabel      matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        HeightINEditField            matlab.ui.control.NumericEditField
        HeightINEditFieldLabel       matlab.ui.control.Label
        GenderDropDown               matlab.ui.control.DropDown
        GenderLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GETSTARTEDButton
        function GETSTARTEDButtonPushed(app, event)
        if strcmpi(app.Panel_2.Visible, 'on')
            app.Panel_2.Visible = 'off';
        else
            app.Panel_2.Visible = 'on';
        end
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)

        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
        if strcmpi(app.Panel_3.Visible, 'on')
            app.Panel_3.Visible = 'off';
        else
            app.Panel_3.Visible = 'on';
        end     
        end

        % Value changed function: HeightINEditField
        function HeightINEditFieldValueChanged(app, event)
             
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
          if strcmpi(app.Panel_4.Visible, 'on')
            app.Panel_4.Visible = 'off';
        else
            app.Panel_4.Visible = 'on';
        end        
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
        if strcmpi(app.Panel_5.Visible, 'on')
            app.Panel_5.Visible = 'off';
        else
            app.Panel_5.Visible = 'on';
        end       
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
        if strcmpi(app.Panel_6.Visible, 'on')
            app.Panel_6.Visible = 'off';
        else
            app.Panel_6.Visible = 'on';
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.PleaseEnterInformationPanel = uipanel(app.Panel);
            app.PleaseEnterInformationPanel.Title = 'Please Enter Information:';
            app.GenderLabel = uilabel(app.PleaseEnterInformationPanel);
            app.GenderLabel.Text = 'Gender:        ';
            app.GenderDropDown = uidropdown(app.PleaseEnterInformationPanel);
            app.GenderDropDown.Items = {'Male', 'Female', 'Prefer Not To Say'};
            app.GenderDropDown.Value = 'Male';
            app.HeightINEditFieldLabel = uilabel(app.PleaseEnterInformationPanel);
            app.HeightINEditFieldLabel.Text = 'Height (IN) :';
            app.HeightINEditField = uieditfield(app.PleaseEnterInformationPanel, 'numeric');
            app.HeightINEditField.ValueChangedFcn = createCallbackFcn(app, @HeightINEditFieldValueChanged, true);
            app.RESETButton = uibutton(app.PleaseEnterInformationPanel, 'push');
            app.RESETButton.Text = 'RESET';
            app.WeightlbsEditFieldLabel = uilabel(app.PleaseEnterInformationPanel);
            app.WeightlbsEditFieldLabel.Text = 'Weight (lbs) :';
            app.WeightlbsEditField = uieditfield(app.PleaseEnterInformationPanel, 'numeric');
            app.GETSTARTEDButton = uibutton(app.PleaseEnterInformationPanel, 'push');
            app.GETSTARTEDButton.ButtonPushedFcn = createCallbackFcn(app, @GETSTARTEDButtonPushed, true);
            app.GETSTARTEDButton.Text = 'GET STARTED';
            app.Image = uiimage(app.PleaseEnterInformationPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HackUTA', 'ffd2b1502f7ad1c081e559672a6fad44.jpg');
            app.Image_2 = uiimage(app.PleaseEnterInformationPanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'HackUTA', 'ffd2b1502f7ad1c081e559672a6fad44.jpg');
            app.HackUTA2023Label = uilabel(app.Panel);
            app.HackUTA2023Label.Text = 'HackUTA2023';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Visible = 'off';
            app.BodyMassIndexBMILabel = uilabel(app.Panel_2);
            app.BodyMassIndexBMILabel.Text = 'Body Mass Index (BMI) : ';
            app.BodyMassIndexBMIEditField = uieditfield(app.Panel_2, 'numeric');
            app.BodyMassIndexBMIEditField.Editable = 'off';
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.Panel_2);
            app.NEXTButton = uibutton(app.Panel_2, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.BACKButton = uibutton(app.Panel_2, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.Panel_3 = uipanel(app.Panel_2);
            app.Panel_3.Visible = 'off';
            app.BasedonyourBMIthesearetheaverageamountofstepsyouneedtotakeLabel = uilabel(app.Panel_3);
            app.BasedonyourBMIthesearetheaverageamountofstepsyouneedtotakeLabel.Text = {'Based on your BMI, '; 'these are the average amount of steps you need to take:'};
            app.EditField = uieditfield(app.Panel_3, 'numeric');
            app.EditField.Editable = 'off';
            app.OKButton = uibutton(app.Panel_3, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.BACKButton_2 = uibutton(app.Panel_3, 'push');
            app.BACKButton_2.Text = 'BACK';
            app.Panel_4 = uipanel(app.Panel_3);
            app.Panel_4.Visible = 'off';
            app.STARTButton = uibutton(app.Panel_4, 'push');
            app.STARTButton.Text = 'START';
            app.PressSTARTButtonToBeginWorkoutLabel = uilabel(app.Panel_4);
            app.PressSTARTButtonToBeginWorkoutLabel.Text = 'Press "START"  Button To Begin Workout';
            app.STOPButton = uibutton(app.Panel_4, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_5.Visible = 'off';
            app.HereisaSummaryofyourWorkoutLabel = uilabel(app.Panel_5);
            app.HereisaSummaryofyourWorkoutLabel.Text = 'Here is a Summary of your Workout:';
            app.Image2 = uiimage(app.Panel_5);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'HackUTA', 'LatLongCollected.jpg');
            app.NextButton = uibutton(app.Panel_5, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.Panel_6 = uipanel(app.Panel_5);
            app.Panel_6.Visible = 'off';
            app.YouBurnedkcalEditFieldLabel = uilabel(app.Panel_6);
            app.YouBurnedkcalEditFieldLabel.Text = 'You Burned (kcal)';
            app.YouBurnedkcalEditField = uieditfield(app.Panel_6, 'numeric');
            app.StepsEditFieldLabel = uilabel(app.Panel_6);
            app.StepsEditFieldLabel.Text = 'Steps: ';
            app.StepsEditField = uieditfield(app.Panel_6, 'numeric');
            app.FINISHEDButton = uibutton(app.Panel_6, 'push');
            app.FINISHEDButton.Text = 'FINISHED';
            app.GitFitLabel = uilabel(app.UIFigure);
            app.GitFitLabel.Text = 'GitFit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GitFit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end